classdef StartGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        accuracy         matlab.ui.control.Label
        WelcomeLabel     matlab.ui.control.Label
        EnterNameButton  matlab.ui.control.Button
        TextArea         matlab.ui.control.TextArea
        Label            matlab.ui.control.Label
        LifeVal          matlab.ui.control.Label
        LivesLabel       matlab.ui.control.Label
        Answer           matlab.ui.control.Label
        Wordspell        matlab.ui.control.Label
        Image            matlab.ui.control.Image
        Letter_Disp      matlab.ui.control.Label
        ExitButton       matlab.ui.control.Button
        ScoreVal         matlab.ui.control.Label
        ScoreLabel       matlab.ui.control.Label
        TimeData         matlab.ui.control.Label
        TimeLabel        matlab.ui.control.Label
        StartButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        cam; % Description
        t;
        count=1;
        
        currscore=0; % Description
    end
    
    methods (Access = private)
        
        function toggleButton(app)
            app.TimeData.Text = num2str(str2double(app.TimeData.Text) - 1);

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = timer;
            app.t.Period = 1;
            load HandTalkerNetv9;
            app.cam = webcam;
            while true
                frame = snapshot(app.cam);
                snapped=imresize(frame,[227 227]);
                [label,acc]=classify(HandTalkerNetv9,snapped);
                app.accuracy.Text=num2str(max(acc));
                app.Letter_Disp.Text = label;
                app.Image.ImageSource= frame;
                wordisp=string(app.Wordspell.Text);
                wordans=string(app.Answer.Text);
                if app.TimeData.Text =='0' |wordisp==wordans
                    app.Answer.Text="*******";
                    app.Wordspell.Text="_______";
                    if str2double(app.TimeData.Text)>60
                        score=300;
                    elseif str2double(app.TimeData.Text)>30
                        score=200;
                    elseif str2double(app.TimeData.Text)>0
                        score=100;
                    else
                        score=0;
                        app.LifeVal.Text=num2str(str2double(app.LifeVal.Text)-1);
                    end
                    score = score+app.currscore+str2double(app.ScoreVal.Text);
                    app.ScoreVal.Text=num2str(score);
                    app.count=1;
                    app.currscore=0;
                    stop(app.t);
                    app.TimeData.Text ='Time Out!!!';
                    if app.LifeVal.Text~='0'
                        app.StartButton.Enable="on";
                    else
                        highscore = readtable("Book1.xlsx", "Sheet", 1);
                        highscore=sortrows(highscore,2,"descend");
                        lowesthighscore=table2array(highscore(10,2));
                        if str2double(app.ScoreVal.Text)>lowesthighscore
                            message = {'Youve Beaten a HighScore!!!'};
                            uialert(app.UIFigure,message,'Congratulations!!!','Icon','warning');
                            highscore(10,1)=app.TextArea.Value;
                            highscore(10,2)=array2table(str2double(app.ScoreVal.Text));
                            highscore=sortrows(highscore,2,"descend");
                            writetable(highscore,'Book1.xlsx')
                        else
                            message = {'You ran out of Lives!!!'};
                            uialert(app.UIFigure,message,'Game Over!!!','Icon','warning');
                        end
                    end
                end
                currentlet= string(extract(app.Wordspell.Text,app.count));
                if currentlet ==app.Letter_Disp.Text
                    app.Answer.Text=append(app.Answer.Text,currentlet);
                    app.count=app.count+1;
                    point=max(acc) * 100;
                    app.currscore= app.currscore + round(point);


                end
            end
        end

        % Callback function: StartButton, UIFigure
        function StartButtonPushed(app, event)
             %timer
            app.StartButton.Enable="off";
             stop(app.t);
             app.Answer.Text="";
             app.count=1;
            app.TimeData.Text = '120';
            app.t.ExecutionMode = "fixedRate";
            app.t.TasksToExecute = 120;
            app.t.TimerFcn = @(~,thisEvent) toggleButton(app);
            start(app.t)
            
            fid=fopen('dictionary.txt','r');
            Nrows = numel(textread('dictionary.txt','%1c%*[^\n]'));
            linenum = randi(Nrows);
            
            c=textscan(fid,'%s',1,'Delimiter','\n','headerlines',linenum-1);
            app.Wordspell.Text=c{:};


        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq();
        end

        % Callback function
        function HelpButtonPushed(app, event)
            while true

            end
        end

        % Button pushed function: EnterNameButton
        function EnterNameButtonPushed(app, event)
            app.StartButton.Enable="on";
            app.TextArea.Enable='off';
            app.EnterNameButton.Enable="off";
            app.TextArea.Value
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Time:';
            app.TimeData = uilabel(app.UIFigure);
            app.TimeData.Text = '120';
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score:';
            app.ScoreVal = uilabel(app.UIFigure);
            app.ScoreVal.Text = '0';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Letter_Disp = uilabel(app.UIFigure);
            app.Letter_Disp.Text = 'Z';
            app.Image = uiimage(app.UIFigure);
            app.Wordspell = uilabel(app.UIFigure);
            app.Wordspell.Text = '_______';
            app.Answer = uilabel(app.UIFigure);
            app.Answer.Text = '*******';
            app.LivesLabel = uilabel(app.UIFigure);
            app.LivesLabel.Text = 'Lives:';
            app.LifeVal = uilabel(app.UIFigure);
            app.LifeVal.Text = '1';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Placeholder = 'Name Here';
            app.EnterNameButton = uibutton(app.UIFigure, 'push');
            app.EnterNameButton.ButtonPushedFcn = createCallbackFcn(app, @EnterNameButtonPushed, true);
            app.EnterNameButton.Text = 'Enter Name';
            app.WelcomeLabel = uilabel(app.UIFigure);
            app.WelcomeLabel.Text = 'Welcome';
            app.accuracy = uilabel(app.UIFigure);
            app.accuracy.Text = '0.00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StartGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end